classdef Audio_Equalizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PlayButton                    matlab.ui.control.Button
        doubleFsButton                matlab.ui.control.Button
        halfFsButton                  matlab.ui.control.Button
        TimeDomainPanel               matlab.ui.container.Panel
        ApplynewfiltersButton         matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        AfterLabel_3                  matlab.ui.control.Label
        BeforeLabel_3                 matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        ModeButtonGroup               matlab.ui.container.ButtonGroup
        CustomButton                  matlab.ui.control.RadioButton
        StandardButton                matlab.ui.control.RadioButton
        SamplingRateEditField_2       matlab.ui.control.NumericEditField
        SamplingRateEditField_2Label  matlab.ui.control.Label
        EnterButton                   matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        gain_20k                      matlab.ui.control.Slider
        KHzLabel_4                    matlab.ui.control.Label
        gain_14k                      matlab.ui.control.Slider
        KhzLabel                      matlab.ui.control.Label
        gain_12k                      matlab.ui.control.Slider
        KHzLabel_2                    matlab.ui.control.Label
        gain_6k                       matlab.ui.control.Slider
        KHzLabel_3                    matlab.ui.control.Label
        gain_610                      matlab.ui.control.Slider
        HzLabel_2                     matlab.ui.control.Label
        gain_300                      matlab.ui.control.Slider
        Label                         matlab.ui.control.Label
        gain_170                      matlab.ui.control.Slider
        HzLabel                       matlab.ui.control.Label
        FreuquencyDomainPanel         matlab.ui.container.Panel
        BeforeLabel_2                 matlab.ui.control.Label
        AfterLabel                    matlab.ui.control.Label
        PhaseLabel                    matlab.ui.control.Label
        MagnitudeLabel                matlab.ui.control.Label
        UIAxes5                       matlab.ui.control.UIAxes
        UIAxes6                       matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        FrequencyBandGainsPanel       matlab.ui.container.Panel
        gain_3k                       matlab.ui.control.Slider
        KHzLabel                      matlab.ui.control.Label
        gain_1005                     matlab.ui.control.Slider
        HzLabel_3                     matlab.ui.control.Label
        PlotButton                    matlab.ui.control.Button
        BandtoPlotDropDown            matlab.ui.control.DropDown
        BandtoPlotDropDownLabel       matlab.ui.control.Label
        SaveButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        FilterTypeButtonGroup         matlab.ui.container.ButtonGroup
        IIRButton                     matlab.ui.control.RadioButton
        FIRButton                     matlab.ui.control.RadioButton
        LocationEditField             matlab.ui.control.EditField
        LocationEditFieldLabel        matlab.ui.control.Label
        BrowseButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        y % resampled wave
        fir_order = 40
        iir_order = 4
        fs % sampling frequency
        t % time
        fm % frequency/2
        fo % sampling rate output
        Ns % number of samples
        bandgains = ones(1,9); % gains of each band 
        newfs % inputted sampling rate
        new_signal % Description
        Mode="Standard" %App Mode: Standard or Custom
        CustomBands;
        CustomGains;
        FilterType;
        PredefinedBands;
        FilterTypeFIR;
        FilterTypeIIR;
        FilterOrder;
    end
    
    methods (Access = private)
        
        function x=showCustomBandDialog(app)
           % Create a new figure (popup)
        d = uifigure('Name', 'Enter Custom Bands', 'Position', [500 500 300 200]);
    
        % Label
        uilabel(d, ...
            'Text', 'Enter frequency edges (comma-separated):', ...
            'Position', [20 130 260 30]);
    
        % Input field
        bandField = uieditfield(d, ...
            'Position', [20 100 260 25], ...
            'Tag', 'BandField');
    
        % Confirm Button
        uibutton(d, ...
            'Text', 'Confirm', ...
            'Position', [100 50 100 30], ...
            'ButtonPushedFcn', @(btn,event)confirmBands(app, d, bandField)); 
        x=d;
            end
            
        function confirmBands(app, dialog, bandField)
                str = bandField.Value;
        edges = str2num(str);  % Parse comma-separated input
    
        % Validation
            if isempty(edges) || edges(1) ~= 0 || edges(end) ~= 20000 || ...
           length(edges) < 6 || length(edges) > 11 || any(diff(edges) <= 0)
            uialert(dialog, 'Input must be 0–20kHz, 5–10 bands, ascending.', 'Invalid Input');
            return;
            end

            % Store the bands and close dialog
            app.CustomBands = edges;
            disp("Custom bands accepted:");
            disp(edges);
            close(dialog);
        end

        function showCustomGainDialogBox(app)
            numBands = length(app.CustomBands) - 1;
            prompts = cell(1, numBands);  % Preallocate
            default_vals = cell(1, numBands);

            for i = 1:numBands
                prompt{i} = sprintf('Enter gain for band %d (dB):', i);
                default_vals{i} = '0';
            end
            dlg_title = 'Gain (in dB)';
            num_lines = 1;
            
            answer = inputdlg(prompts, dlg_title, num_lines, default_vals);
            if isempty(answer)
                errordlg('Gain input canceled by user.');
                return;
            end

            gains = zeros(1, length(answer));
            for i = 1:length(answer)
                val = str2double(answer{i});  % Convert String to A number tto store the gain
                if isnan(val)
                    errordlg(sprintf('Invalid gain input at band %d', i));
                    return;
                end
                gains(i) = val;
            end
            app.CustomGains=gains;
            disp("Custom bands accepted:");
            disp(gains);
        end

  function filterTypeIIR = getIIRFilterType(app)
    % Loop to ensure the user selects a valid filter type
     validFilters = {'BUTTERWORTH', 'CHEBYSHEV I', 'CHEBYSHEV II'};
            while true
                prompt = {'Select IIR filter type (Butterworth, Chebyshev I, Chebyshev II):'};
                dlg_title = 'IIR Filter Type';
                num_lines = 1;
                default_answer = {'Butterworth'};  % Default value
                answer = inputdlg(prompt, dlg_title, num_lines, default_answer);
                
                if isempty(answer)
                    filterTypeIIR = ''; % No selection made, exit
                    return;
                end
        
        % Extract the filter type from the input dialog
        filterTypeIIR = answer{1};
        
        % Convert user input to uppercase to avoid case sensitivity issues
        filterTypeIIR = strtrim(filterTypeIIR); % Remove leading/trailing spaces
       
        
        % Check if the selected filter type is valid (case-insensitive)
                 if ismember(upper(filterTypeIIR), validFilters)
                    break;  % Exit the loop if the filter type is valid
                else
            % Display an error dialog and prompt the user to try again
            h= errordlg('Invalid Filter Type. Please select a valid filter.', 'Error');
            waitfor(h);
                end
            end
    
    % Continue with the selected valid filter type
    disp(['Selected IIR Filter Type: ', filterTypeIIR]);
        end
        
      function filterTypeFIR = getFIRFilterType(app)
    % Loop to ensure the user selects a valid filter type
     validFilters = {'HAMMING', 'HANNING', 'BLACKMAN'};
            while true
                prompt = {'Select FIR filter type (Hamming, Hanning, or Blackman):'};
                dlg_title = 'FIR Filter Type';
                num_lines = 1;
                default_answer = {'Hamming'};  % Default value
                answer = inputdlg(prompt, dlg_title, num_lines, default_answer);
                
                if isempty(answer)
                    filterTypeFIR = ''; % No selection made, exit
                    return;
                end
        
        % Extract the filter type from the input dialog
        filterTypeFIR = answer{1};
        
        % Convert user input to uppercase to avoid case sensitivity issues
        filterTypeFIR = strtrim(filterTypeFIR); % Remove leading/trailing spaces
       
        
        % Check if the selected filter type is valid (case-insensitive)
                 if ismember(upper(filterTypeFIR), validFilters)
                    break;  % Exit the loop if the filter type is valid
                else
            % Display an error dialog and prompt the user to try again
            h= errordlg('Invalid Filter Type. Please select a valid filter.', 'Error');
            waitfor(h);
                end
            end
    
    % Continue with the selected valid filter type
    disp(['Selected FIR Filter Type: ', filterTypeFIR]);
      end
       function filterOrder = getFilterOrder(app)
   
            while true
                prompt = {'Enter filter order (positive integer):'};
                dlg_title = 'Filter Order';
                num_lines = 1;
                default_answer = {'5'};  % Default value
                answer = inputdlg(prompt, dlg_title, num_lines, default_answer);
                
                if isempty(answer)
                    filterOrder=[];
                    return;
                end
        
        
                orderVal = str2double(answer{1});
        
                if isnumeric(orderVal) && isfinite(orderVal) && orderVal > 0 && mod(orderVal, 1) == 0
                filterOrder = orderVal;
                return;
                else
                 h = errordlg('Invalid filter order. Please enter a positive integer.', 'Error');
                 waitfor(h);  % Wait until user closes error dialog
                end
            end
       end
       function filters = designIIRFilters(app)
        numBands = length(app.CustomBands) - 1;
        filters = cell(1, numBands);
        
            for i = 1:numBands
            bandStart = app.CustomBands(i);       % Start frequency of the band
            bandEnd = app.CustomBands(i + 1);     % End frequency of the band
            
            % Design an IIR filter for each band
            if i == 1
                [b, a] = butter(4, bandEnd / (app.fs / 2));  % 4th order lowpass filter
            elseif i > 1 && i < numBands
                [b, a] = butter(4, [bandStart, bandEnd] / (app.fs / 2), 'bandpass');
            else
                [b, a] = butter(4, bandStart / (app.fs / 2), 'high');  % Highpass filter
            end
            
            % Store the filter coefficients
            filters{i} = struct('b', b, 'a', a, 'startFreq', bandStart, 'endFreq', bandEnd);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
           [FileName,FilePath]=uigetfile({'*.wav'});
           fullPath = [FilePath FileName];
           app.LocationEditField.Value = fullPath;
           [app.y,app.fs] = audioread(app.LocationEditField.Value);
           fprintf('The value of Sampling Frequency is %.2f\n', app.fs);
           app.y = app.y(:,1);   
           app.y = transpose(app.y);
           app.Ns = length(app.y);
           app.t = linspace(0, app.Ns/app.fs, app.Ns);
           app.fm = app.fs/2;

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            gains = [app.gain_170.Value app.gain_300.Value app.gain_610.Value app.gain_1005.Value app.gain_3k.Value app.gain_6k.Value app.gain_12k.Value app.gain_14k.Value app.gain_20k.Value];  
            app.bandgains = db2mag(gains);

            freq = [0,170,300,610,1005,3000,6000,12000,14000,20000];                                   
            x =  str2double(app.BandtoPlotDropDown.Value);
            

            if app.FilterTypeButtonGroup.SelectedObject == app.IIRButton

                if x == 1
                    [b, a] = butter(app.iir_order,170/(app.fs/2), 'low');
                else

                    [b, a] = butter(app.iir_order,[freq(x) freq(x+1)]/(app.fs/2),'bandpass');
                end
                type = 'IIR';
            
            else
                if x == 1
                    b = fir1(app.fir_order,170/(app.fs/2),'low');
                else
                    b = fir1(app.fir_order,[freq(x) freq(x+1)]/(app.fs/2),'bandpass');
                end

                a = 1;
                type = 'FIR';

            end
            

            title1 = ['Gain and Phase response of ', num2str(freq(x)), ' - ' , num2str(freq(x+1)), ' Hz filter'];
            title2 = ['Impulse response of ', num2str(freq(x)), ' - ' , num2str(freq(x+1)), ' Hz filter'];
            title3 = ['Step response of ', num2str(freq(x)), ' - ' , num2str(freq(x+1)), ' Hz filter'];
            title4 = ['Zeros and Poles of ', num2str(freq(x)), ' - ' , num2str(freq(x+1)), ' Hz filter'];
            title5 = ['Time Domain signal with (', num2str(freq(x)), ' - ' , num2str(freq(x+1)), ' Hz) ', type, ' filter'];
            title6 = 'Magnitude of filtered signal in frequency domain';
            title7 = 'Phase of filtered signal in frequency domain';

            figure;
            freqz(b, a); 
            title(title1);

            figure;
            subplot(2,2,1);
            impz(b,a); 
            title(title2);
            subplot(2,2,2);
            stepz(b,a); 
            title(title3);


            [z,p, ~]  = tf2zpk(b,a);
            subplot(2,2,[3,4]);
            zplane(z,p); 
            title(title4);


            filteredSignal = app.bandgains(x) * filter(b,a,app.y); 
            figure;
            subplot(3,1,1)
            plot(app.t,filteredSignal);
            title(title5);            
            xlabel('Time in seconds');
            ylabel('Amplitude');


            subplot(3,1,2);
            fmag = abs(fftshift(fft(filteredSignal))/app.fs);           
            f_xaxis = linspace(-app.fs/2,app.fs/2,app.Ns);
            plot(f_xaxis,fmag); %filtered signal in frequency domain
            title(title6);
            xlabel('Frequency (Hz)'); 
            ylabel('Magnitude');


            phase = angle(fftshift(fft(filteredSignal)));
            subplot(3,1,3)
            plot(f_xaxis,phase);
            title(title7); 
            xlabel('Frequency (Hz)'); 
            ylabel('Phase');
            
            
            %fvtool(b, a);

            
        end

        % Callback function
        function samplingrateEditFieldValueChanged(app, event)

            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.bandgains = ones(1,9);
            app.gain_170.Value = 0;
            app.gain_300.Value = 0;
            app.gain_610.Value = 0;
            app.gain_1005.Value = 0;
            app.gain_3k.Value = 0;
            app.gain_6k.Value = 0;
            app.gain_12k.Value = 0;
            app.gain_14k.Value = 0;
            app.gain_20k.Value = 0;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            %Time Domain
            plot(app.UIAxes,app.t,app.y) % elly taht 3la el shemal
            t_new = linspace(0,length(app.new_signal)/app.newfs,length(app.new_signal));
            plot(app.UIAxes2,t_new,app.new_signal); % elly fo2 3la el yemin
            
            %Frequency Domain           
            L1 = length(app.y);
            L2 = length(app.new_signal);
            f_xaxis1 = linspace(-app.fs/2,app.fs/2,L1);
            f_xaxis2 = linspace(-app.newfs/2,app.newfs/2,L2);

            %Frequency Magnitude            
            fmag1 = abs(fftshift(fft(app.y))/app.fs); %we divide by L1 to normalize            
            plot(app.UIAxes4,f_xaxis1,fmag1); % 3la el yemin taht khales
            
             % we divide by L2 to normalize
            fmag2 = abs(fftshift(fft(app.new_signal))/app.newfs);             
            plot(app.UIAxes3,f_xaxis2,fmag2); % 3la el shemal taht khales


            %Frequency Phase   
            fphase1 = angle(fftshift(fft(app.y)));  
            plot(app.UIAxes6,f_xaxis1,fphase1); % 3la el yemin taht khales
            fphase2 = angle(fftshift(fft(app.new_signal)));
            plot(app.UIAxes5,f_xaxis2,fphase2); % 3la el shemal taht khales
                  
        end

        % Button pushed function: ApplynewfiltersButton
        function ApplynewfiltersButtonPushed(app, event)
            gains = [app.gain_170.Value app.gain_300.Value app.gain_610.Value app.gain_1005.Value app.gain_3k.Value app.gain_6k.Value app.gain_12k.Value app.gain_14k.Value app.gain_20k.Value];                       
            app.bandgains = db2mag(gains);            
            app.newfs = app.SamplingRateEditField.Value();
            app.PredefinedBands = [0,200,500,800,1200,3000,6000,12000,16000,20000];
            app.new_signal =zeros(1,length(app.y));

            if app.FilterTypeButtonGroup.SelectedObject == app.FIRButton
                for x = 1:9
                    if x==1
                        b = fir1(app.fir_order,170/app.fm);
                    else 
                        b = fir1(app.fir_order,[freq(x) freq(x+1)]/app.fm,'bandpass');
                    end
                    filteredSignal = filter(b,1,app.y); %filtered signal in time domain
                    app.new_signal = app.new_signal + (filteredSignal*app.bandgains(x));
                end
            else                 
                
                for x = 1:9

                     if x==1
                         [b,a] = butter(app.iir_order,170/app.fm);
                     else 
                         [b,a] = butter(app.iir_order,[freq(x) freq(x+1)]/app.fm,'bandpass');
                     end

                    filteredSignal = filter(b,a,app.y);                    
                    app.new_signal = app.new_signal + (filteredSignal*app.bandgains(x));
                end
            end
            if strcmpi(app.newfs,'Enter new Sampling rate') == 1
                app.newfs = app.fs;
            else
                app.newfs = str2double(app.newfs);
            end
            %app.new_signal = resample(app.new_signal,app.newfs,app.fs); 
                
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            audiowrite("output_file_half.wav",app.new_signal, app.newfs/2);
            audiowrite("output_file_double.wav",app.new_signal, app.newfs*2);

            
        end

        % Button pushed function: doubleFsButton
        function doubleFsButtonPushed(app, event)
            app.newfs = app.fs*2;
            sound(app.y, app.newfs );
            disp("Playing signal at double the sampling frequency...");
            % Time domain plot
            L1 = length(app.y);
            app.t = (0:L1-1) / (app.newfs );
            
            figure;
            subplot(2,1,1);
            plot(app.t, app.y);
            title("Output Signal for Double Fs");
            xlabel("Time (s)");
            ylabel("Amplitude");
        
            % Frequency domain plot
            fmag1 = abs(fftshift(fft(app.y))) / L1;
            f_xaxis1 = linspace(-app.newfs/2, app.newfs/2, L1);
            
            subplot(2,1,2);
            plot(f_xaxis1, fmag1);
            title("Magnitude Spectrum for Double Fs");
            xlabel("Frequency (Hz)");
            ylabel("Magnitude");
        end

        % Button pushed function: halfFsButton
        function halfFsButtonPushed(app, event)

            app.newfs = app.fs / 2;
       
        % Play the signal at half the original sampling frequency
        sound(app.y, app.newfs);

        % Time domain plot
        half_t = linspace(0, length(app.y)/app.newfs, length(app.y));
        figure;
        subplot(2,1,1);
        plot(half_t, app.y);
        title("Output signal for Half FS");
        xlabel("Time (s)");
        ylabel("Amplitude");
    
        % Frequency domain plot
        L = length(app.y);
        Y = fft(app.y);
        f = linspace(-app.newfs/2, app.newfs/2, L);
        fmag = abs(fftshift(Y)) / L; % normalize by signal length
    
        subplot(2,1,2);
        plot(f, fmag);
        title("Magnitude Spectrum at Half FS");
        xlabel("Frequency (Hz)");
        ylabel("Magnitude");
                
        end

        % Callback function
        function SamplingRateEditFieldValueChanged(app, event)

          
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            app.SamplingRateEditField_2.ValueDisplayFormat = '%.0f';
             value = app.SamplingRateEditField_2.Value;  % Get the value from the Edit Field

    % Check if the value is valid (numeric and positive)
            if ~isnumeric(value) || isnan(value) || value <= 0
        uialert(app.UIFigure, ...
                'Sampling rate must be a positive number.', ...
                'Invalid Input');
        app.SamplingRateEditField_2.Value = 44100;  % Reset to default if invalid
            else
        app.fo = value;  % Store valid value
        fprintf("Output Sampling Rate: %d\n", app.fo);
            end
        end

        % Callback function
        function SamplingRateEditField_2ValueChanged(app, event)
          
            
        end

        % Value changed function: SamplingRateEditField_2
        function SamplingRateEditField_2ValueChanged2(app, event)
        
        end

        % Selection changed function: ModeButtonGroup
        function ModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ModeButtonGroup.SelectedObject;
                if selectedButton == app.StandardButton
        % User selected Standard mode
        disp("Standard Mode Selected");
        app.Mode = "Standard";
        
             elseif selectedButton == app.CustomButton
        % User selected Custom mode
        disp("Custom Mode Selected");
        app.Mode = "Custom";
        x=app.showCustomBandDialog();
        waitfor(x);
        app.showCustomGainDialogBox();
        end
          
        end

        % Selection changed function: FilterTypeButtonGroup
        function FilterTypeButtonGroupSelectionChanged(app, event)
            selectedFilter = app.FilterTypeButtonGroup.SelectedObject;
                if selectedFilter == app.FIRButton
                 app.FilterType = 'FIR';
                 disp("FIR filter Selected");
                 app.FilterTypeFIR=app.getFIRFilterType();
                 elseif selectedFilter == app.IIRButton
                disp("IIR filter Selected");
                app.FilterType = 'IIR';
                app.FilterTypeIIR = app.getIIRFilterType();
                end

              app.FilterOrder=app.getFilterOrder();
        end

        % Value changed function: BandtoPlotDropDown
        function BandtoPlotDropDownValueChanged(app, event)
            value = app.BandtoPlotDropDown.Value;
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if isempty(app.y) || isempty(app.fs)
        uialert(app.UIFigure, 'Signal or sampling frequency not set.', 'Playback Error');
        return;
    end

    sound(app.y, app.fs);
    disp("Playing signal ...");
    % Time-Domain Plot
figure;
subplot(2,1,1);
plot(app.t, app.y);
xlabel('Time (s)');
ylabel('Amplitude');
title('Input Signal - Time Domain');

% Frequency-Domain Plot
L = length(app.y);
f_axis = linspace(-app.fs/2, app.fs/2, L);
Y_fft = fftshift(fft(app.y)/L); % Normalize
fmag = abs(Y_fft);

subplot(2,1,2);
plot(f_axis, fmag);
xlabel('Frequency (Hz)');
ylabel('Magnitude');
title('Input Signal - Frequency Domain');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.LocationEditFieldLabel = uilabel(app.UIFigure);
            app.LocationEditFieldLabel.Text = 'Location';
            app.LocationEditField = uieditfield(app.UIFigure, 'text');
            app.FilterTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.FilterTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterTypeButtonGroupSelectionChanged, true);
            app.FilterTypeButtonGroup.Title = 'Filter Type';
            app.FIRButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.FIRButton.Text = 'FIR';
            app.FIRButton.Value = true;
            app.IIRButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.IIRButton.Text = 'IIR';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.BandtoPlotDropDownLabel = uilabel(app.UIFigure);
            app.BandtoPlotDropDownLabel.Text = {'Band to Plot'; ''};
            app.BandtoPlotDropDown = uidropdown(app.UIFigure);
            app.BandtoPlotDropDown.Items = {'0-200 Hz', '200-500 Hz', '500-800 Hz', '800-1200 Hz', '1.2-3 KHz', '3-6 KHz', '6-12 KHz', '12-16 KHz', '16-20 KHz'};
            app.BandtoPlotDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.BandtoPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @BandtoPlotDropDownValueChanged, true);
            app.BandtoPlotDropDown.Value = '3';
            app.Panel = uipanel(app.UIFigure);
            app.FrequencyBandGainsPanel = uipanel(app.Panel);
            app.FrequencyBandGainsPanel.Title = 'Frequency Band Gains';
            app.PlotButton = uibutton(app.FrequencyBandGainsPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.HzLabel_3 = uilabel(app.FrequencyBandGainsPanel);
            app.HzLabel_3.Text = '800-1200Hz';
            app.gain_1005 = uislider(app.FrequencyBandGainsPanel);
            app.gain_1005.Limits = [-12 12];
            app.gain_1005.MajorTicks = [-12 -9 -6 -3 0 3 6 9 12];
            app.gain_1005.MajorTickLabels = {'-12,', '-9,', '-6,', '-3,', '0,', '3,', '6,', '9,', '12'};
            app.gain_1005.MinorTicks = [-12 -11.4 -10.8 -10.2 -9.6 -9 -8.4 -7.8 -7.2 -6.6 -6 -5.4 -4.8 -4.2 -3.6 -3 -2.4 -1.8 -1.2 -0.6 0 0.6 1.2 1.8 2.4 3 3.6 4.2 4.8 5.4 6 6.6 7.2 7.8 8.4 9 9.6 10.2 10.8 11.4 12];
            app.KHzLabel = uilabel(app.FrequencyBandGainsPanel);
            app.KHzLabel.Text = '1.2-3 KHz';
            app.gain_3k = uislider(app.FrequencyBandGainsPanel);
            app.gain_3k.Limits = [-12 12];
            app.FreuquencyDomainPanel = uipanel(app.Panel);
            app.FreuquencyDomainPanel.Title = 'Freuquency Domain';
            app.UIAxes3 = uiaxes(app.FreuquencyDomainPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'time(s)')
            ylabel(app.UIAxes3, 'magnitude')
            app.UIAxes4 = uiaxes(app.FreuquencyDomainPanel);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'time(s)')
            ylabel(app.UIAxes4, 'magnitude')
            app.UIAxes6 = uiaxes(app.FreuquencyDomainPanel);
            xlabel(app.UIAxes6, 'time(s)')
            ylabel(app.UIAxes6, 'phase')
            app.UIAxes5 = uiaxes(app.FreuquencyDomainPanel);
            title(app.UIAxes5, {''; ''})
            xlabel(app.UIAxes5, 'time(s)')
            ylabel(app.UIAxes5, 'phase')
            app.MagnitudeLabel = uilabel(app.FreuquencyDomainPanel);
            app.MagnitudeLabel.Text = 'Magnitude';
            app.PhaseLabel = uilabel(app.FreuquencyDomainPanel);
            app.PhaseLabel.Text = 'Phase';
            app.AfterLabel = uilabel(app.FreuquencyDomainPanel);
            app.AfterLabel.Text = 'After';
            app.BeforeLabel_2 = uilabel(app.FreuquencyDomainPanel);
            app.BeforeLabel_2.Text = 'Before';
            app.HzLabel = uilabel(app.Panel);
            app.HzLabel.Text = '0-200Hz';
            app.gain_170 = uislider(app.Panel);
            app.gain_170.Limits = [-12 12];
            app.Label = uilabel(app.Panel);
            app.Label.Text = '200-500Hz';
            app.gain_300 = uislider(app.Panel);
            app.gain_300.Limits = [-12 12];
            app.HzLabel_2 = uilabel(app.Panel);
            app.HzLabel_2.Text = '500-800Hz';
            app.gain_610 = uislider(app.Panel);
            app.gain_610.Limits = [-12 12];
            app.KHzLabel_3 = uilabel(app.Panel);
            app.KHzLabel_3.Text = '3-6 KHz';
            app.gain_6k = uislider(app.Panel);
            app.gain_6k.Limits = [-12 12];
            app.KHzLabel_2 = uilabel(app.Panel);
            app.KHzLabel_2.Text = '6-12 KHz';
            app.gain_12k = uislider(app.Panel);
            app.gain_12k.Limits = [-12 12];
            app.KhzLabel = uilabel(app.Panel);
            app.KhzLabel.Text = '12-16Khz';
            app.gain_14k = uislider(app.Panel);
            app.gain_14k.Limits = [-12 12];
            app.KHzLabel_4 = uilabel(app.Panel);
            app.KHzLabel_4.Text = '16-20 KHz';
            app.gain_20k = uislider(app.Panel);
            app.gain_20k.Limits = [-12 12];
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.SamplingRateEditField_2Label = uilabel(app.UIFigure);
            app.SamplingRateEditField_2Label.Text = 'Sampling Rate';
            app.SamplingRateEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.SamplingRateEditField_2.ValueChangedFcn = createCallbackFcn(app, @SamplingRateEditField_2ValueChanged2, true);
            app.ModeButtonGroup = uibuttongroup(app.UIFigure);
            app.ModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeButtonGroupSelectionChanged, true);
            app.ModeButtonGroup.Title = 'Mode';
            app.StandardButton = uiradiobutton(app.ModeButtonGroup);
            app.StandardButton.Text = 'Standard';
            app.StandardButton.Value = true;
            app.CustomButton = uiradiobutton(app.ModeButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.TimeDomainPanel = uipanel(app.UIFigure);
            app.TimeDomainPanel.Title = 'Time Domain';
            app.UIAxes = uiaxes(app.TimeDomainPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'time(s)')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.TimeDomainPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'time(s)')
            ylabel(app.UIAxes2, 'Y')
            app.BeforeLabel_3 = uilabel(app.TimeDomainPanel);
            app.BeforeLabel_3.Text = 'Before';
            app.AfterLabel_3 = uilabel(app.TimeDomainPanel);
            app.AfterLabel_3.Text = 'After';
            app.ResetButton = uibutton(app.TimeDomainPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ApplynewfiltersButton = uibutton(app.TimeDomainPanel, 'push');
            app.ApplynewfiltersButton.ButtonPushedFcn = createCallbackFcn(app, @ApplynewfiltersButtonPushed, true);
            app.ApplynewfiltersButton.Text = 'Apply new filters';
            app.halfFsButton = uibutton(app.UIFigure, 'push');
            app.halfFsButton.ButtonPushedFcn = createCallbackFcn(app, @halfFsButtonPushed, true);
            app.halfFsButton.Text = 'half Fs';
            app.doubleFsButton = uibutton(app.UIFigure, 'push');
            app.doubleFsButton.ButtonPushedFcn = createCallbackFcn(app, @doubleFsButtonPushed, true);
            app.doubleFsButton.Text = 'double Fs';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Audio_Equalizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end